classdef ImageProcessing < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        ImageProcessingAppLabel      matlab.ui.control.Label
        EditField                    matlab.ui.control.NumericEditField
        Slider                       matlab.ui.control.Slider
        CloseButton                  matlab.ui.control.Button
        SaveButton                   matlab.ui.control.Button
        BrowseImageButton            matlab.ui.control.Button
        IntensityThresholdingButton  matlab.ui.control.Button
        GammaTransformButton         matlab.ui.control.Button
        LaplacianSharpeningButton    matlab.ui.control.Button
        MedianFilterButton           matlab.ui.control.Button
        ContrastStretchingButton     matlab.ui.control.Button
        UIAxes2                      matlab.ui.control.UIAxes
        UIAxes                       matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        OriginalImage % Description
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: ContrastStretchingButton
        function ContrastStretchingButtonPushed(app, event)
            I = im2gray(app.OriginalImage);
            result = imadjust(I, stretchlim(I, [0.05 0.95]), []);
            imshow(result, 'Parent', app.UIAxes2);    
        end

        % Button pushed function: MedianFilterButton
        function MedianFilterButtonPushed(app, event)
            I = im2gray(app.OriginalImage);
            result = medfilt2(I, [3 3]);
            imshow(result, 'Parent', app.UIAxes2);    
        end

        % Button pushed function: LaplacianSharpeningButton
        function LaplacianSharpeningButtonPushed(app, event)
            I = im2gray(app.OriginalImage);
            h = fspecial('laplacian', 0.2);
            lap = imfilter(double(I), h);
            result = uint8(double(I) - lap);
            imshow(result, 'Parent', app.UIAxes2);    
        end

        % Button pushed function: GammaTransformButton
        function GammaTransformButtonPushed(app, event)
            gamma = app.EditField.Value;
            I = im2gray(app.OriginalImage);
            result = imadjust(I, [0 1], [0 1], gamma);
            imshow(result, 'Parent', app.UIAxes2);    
        end

        % Button pushed function: IntensityThresholdingButton
        function IntensityThresholdingButtonPushed(app, event)
            t = app.Slider.Value / 255;
            I = im2gray(app.OriginalImage);
            result = imbinarize(I, t);
            imshow(result, 'Parent', app.UIAxes2);    
        end

        % Button pushed function: BrowseImageButton
        function BrowseImageButtonPushed(app, event)
            [file, path] = uigetfile({'*.jpg;*.png;*.bmp;*.tif'}, 'Odaberi sliku');
            if isequal(file, 0), return; end
            app.OriginalImage = imread(fullfile(path, file));
            imshow(app.OriginalImage, 'Parent', app.UIAxes);    
        end

        % Button pushed function: SaveButton
        function SaveButtonPushed(app, event)
            [file, path] = uiputfile({'*.png';'*.jpg'}, 'Sačuvaj sliku');
            if isequal(file, 0), return; end
            imwrite(getimage(app.UIAxes2), fullfile(path, file));    
        end

        % Button pushed function: CloseButton
        function CloseButtonPushed(app, event)
            delete(app);    
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Originalna slika')
            app.UIAxes.XTick = [];
            app.UIAxes.YTick = [];
            app.UIAxes2 = uiaxes(app.UIFigure);
            title(app.UIAxes2, 'Obrađena slika')
            zlabel(app.UIAxes2, 'Z')
            app.UIAxes2.XTick = [];
            app.UIAxes2.XTickLabel = '';
            app.UIAxes2.YTick = [];
            app.ContrastStretchingButton = uibutton(app.UIFigure, 'push');
            app.ContrastStretchingButton.ButtonPushedFcn = createCallbackFcn(app, @ContrastStretchingButtonPushed, true);
            app.ContrastStretchingButton.Text = 'Contrast Stretching';
            app.MedianFilterButton = uibutton(app.UIFigure, 'push');
            app.MedianFilterButton.ButtonPushedFcn = createCallbackFcn(app, @MedianFilterButtonPushed, true);
            app.MedianFilterButton.Text = 'Median Filter';
            app.LaplacianSharpeningButton = uibutton(app.UIFigure, 'push');
            app.LaplacianSharpeningButton.ButtonPushedFcn = createCallbackFcn(app, @LaplacianSharpeningButtonPushed, true);
            app.LaplacianSharpeningButton.Text = 'Laplacian Sharpening';
            app.GammaTransformButton = uibutton(app.UIFigure, 'push');
            app.GammaTransformButton.ButtonPushedFcn = createCallbackFcn(app, @GammaTransformButtonPushed, true);
            app.GammaTransformButton.Text = 'Gamma Transform';
            app.IntensityThresholdingButton = uibutton(app.UIFigure, 'push');
            app.IntensityThresholdingButton.ButtonPushedFcn = createCallbackFcn(app, @IntensityThresholdingButtonPushed, true);
            app.IntensityThresholdingButton.Text = 'Intensity Thresholding';
            app.BrowseImageButton = uibutton(app.UIFigure, 'push');
            app.BrowseImageButton.ButtonPushedFcn = createCallbackFcn(app, @BrowseImageButtonPushed, true);
            app.BrowseImageButton.Text = 'Browse Image';
            app.SaveButton = uibutton(app.UIFigure, 'push');
            app.SaveButton.ButtonPushedFcn = createCallbackFcn(app, @SaveButtonPushed, true);
            app.SaveButton.Text = 'Save';
            app.CloseButton = uibutton(app.UIFigure, 'push');
            app.CloseButton.ButtonPushedFcn = createCallbackFcn(app, @CloseButtonPushed, true);
            app.CloseButton.Text = 'Close';
            app.Slider = uislider(app.UIFigure);
            app.Slider.Limits = [0 255];
            app.Slider.Value = 128;
            app.EditField = uieditfield(app.UIFigure, 'numeric');
            app.ImageProcessingAppLabel = uilabel(app.UIFigure);
            app.ImageProcessingAppLabel.Text = 'Image Processing App';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ImageProcessing

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end